classdef CBFcalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadOMEfileButton               matlab.ui.control.Button
        CBFComputationParametersPanel   matlab.ui.container.Panel
        FrametimesecondsEditFieldLabel  matlab.ui.control.Label
        FrametimesecondsEditField       matlab.ui.control.NumericEditField
        SignalToNoiseCutoffPeakDetectionEditFieldLabel  matlab.ui.control.Label
        SignalToNoiseCutoffPeakDetectionEditField  matlab.ui.control.NumericEditField
        PeakProminenceCutoffEditFieldLabel  matlab.ui.control.Label
        PeakProminenceCutoffEditField   matlab.ui.control.NumericEditField
        CutoffAreaCBFMaskEditFieldLabel  matlab.ui.control.Label
        CutoffAreaCBFMaskEditField      matlab.ui.control.NumericEditField
        ComputeCBFButton                matlab.ui.control.Button
        PixelSizemicrometerEditFieldLabel  matlab.ui.control.Label
        PixelSizemicrometerEditField    matlab.ui.control.NumericEditField
        ApplyImmobileFilterToImageSeriesButton_2  matlab.ui.control.StateButton
        frameSliderLabel                matlab.ui.control.Label
        frameSlider                     matlab.ui.control.Slider
        ExportMoviePanel                matlab.ui.container.Panel
        WhatMovieTypeToExportLabel      matlab.ui.control.Label
        WhatMovieTypeToExportDropDown   matlab.ui.control.DropDown
        WhatFrameRateDoYouWantToSaveVideofpsEditFieldLabel  matlab.ui.control.Label
        WhatFrameRateDoYouWantToSaveVideofpsEditField  matlab.ui.control.NumericEditField
        ApplyImmobileFilterToImageSeriesButton  matlab.ui.control.StateButton
        ExportMovieButton               matlab.ui.control.Button
        ExportFirst100FramesTestButton  matlab.ui.control.Button
        GaussianDenoisingFilterSigmapixelsEditFieldLabel  matlab.ui.control.Label
        GaussianDenoisingFilterSigmapixelsEditField  matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        ApplyBatchCBFAnalysisUsingAboveDefinedParameters1Button  matlab.ui.control.Button
        ApplyBatchMovieExportUsingAboveDefinedParameters2Button  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fname
        im % Description
        filt
        timeFrame
        pixelsize
        cutStoN
        promCut
        areaThresh
        movType
        movFPS
        ImmobFilt1
        ImmobFilt2
        Fs
        TotImages
        t
        L
        sliderVal
        gausfilt
        ApplyGaussian
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOMEfileButton
        function LoadOMEfileButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.czi;*.nd2;*.oir;*.oif;*.lif','OME files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                app.fname=fname;
                [im,specs]=readOMEAlternative(app.fname);
                app.im=single(im);
                %updateimage(app, fname);
                meta=GetOMEData(app.fname);
                imagesc(app.UIAxes,squeeze(app.im(:,:,1)));
                colormap(app.UIAxes,'gray')
                app.timeFrame=min(0.003,meta.ScaleT);
                app.pixelsize=meta.ScaleX;
                app.PixelSizemicrometerEditField.Value =app.pixelsize;
                app.FrametimesecondsEditField.Value=app.timeFrame;
                app.cutStoN=5;
                app.promCut=0.05;
                app.areaThresh=10;
                app.movType='mp4';
                app.movFPS=30;
                app.ImmobFilt1=0;
                app.ImmobFilt2=0;
                app.sliderVal=1;
                app.filt=[];
                app.gausfilt=1.5;
                app.ApplyGaussian=0;
                app.TotImages=size(app.im,3);
                app.frameSlider.Limits = [1 size(app.im,3)];
                xlim(app.UIAxes,[1 size(app.im,2)])
                ylim(app.UIAxes,[1 size(app.im,1)])
            end
        end

        % Value changed function: 
        % ApplyImmobileFilterToImageSeriesButton_2
        function ApplyImmobileFilterToImageSeriesButton_2ValueChanged(app, event)
            value = app.ApplyImmobileFilterToImageSeriesButton_2.Value;
            app.ImmobFilt1=value;
            if app.ImmobFilt1==1
                % filtering immobile component
                app.filt = fft(double(app.im),[],3);
                avPixInt = mean(mean(app.filt(:,:,1)))/size(app.filt,3);
                app.filt(:,:,1) = 0;
                app.filt = (real(ifft(app.filt,[],3))+avPixInt);
                app.filt=single(app.filt);
            elseif app.ImmobFilt1==0
                app.filt=app.im;
            end
            cla(app.UIAxes)
            imagesc(app.UIAxes,squeeze(app.filt(:,:,app.sliderVal)));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.filt,2)])
            ylim(app.UIAxes,[1 size(app.filt,1)])
        end

        % Value changing function: frameSlider
        function frameSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sliderVal=ceil(changingValue);
            cla(app.UIAxes)
            if isempty(app.filt)
                imagesc(app.UIAxes,app.im(:,:,app.sliderVal));
            elseif ~isempty(app.filt)
                imagesc(app.UIAxes,app.filt(:,:,app.sliderVal));
            end
            colormap(app.UIAxes,'gray')
            hold(app.UIAxes,"on");
            text(app.UIAxes,size(app.im,2)-40,size(app.im,1)-20,num2str(app.sliderVal),'Color',[1 1 1])
            xlim(app.UIAxes,[1 size(app.im,2)])
            ylim(app.UIAxes,[1 size(app.im,1)])
        end

        % Value changed function: frameSlider
        function frameSliderValueChanged(app, event)
            value = app.frameSlider.Value;
            app.sliderVal=ceil(value);
        end

        % Value changed function: FrametimesecondsEditField
        function FrametimesecondsEditFieldValueChanged(app, event)
            value = app.FrametimesecondsEditField.Value;
            app.timeFrame=value;
        end

        % Value changed function: PixelSizemicrometerEditField
        function PixelSizemicrometerEditFieldValueChanged(app, event)
            value = app.PixelSizemicrometerEditField.Value;
            app.pixelsize=value;
        end

        % Value changed function: 
        % SignalToNoiseCutoffPeakDetectionEditField
        function SignalToNoiseCutoffPeakDetectionEditFieldValueChanged(app, event)
            value = app.SignalToNoiseCutoffPeakDetectionEditField.Value;
            app.cutStoN=value;
        end

        % Value changed function: PeakProminenceCutoffEditField
        function PeakProminenceCutoffEditFieldValueChanged(app, event)
            value = app.PeakProminenceCutoffEditField.Value;
            app.promCut=value;
        end

        % Value changed function: CutoffAreaCBFMaskEditField
        function CutoffAreaCBFMaskEditFieldValueChanged(app, event)
            value = app.CutoffAreaCBFMaskEditField.Value;
            app.areaThresh=value;
        end

        % Button pushed function: ComputeCBFButton
        function ComputeCBFButtonPushed(app, event)
            
            % define some parameters
            app.Fs = 1/app.timeFrame;            % Sampling frequency
            app.L =  app.TotImages;              % Length of signal
            app.t = (0:app.L-1)*app.timeFrame;        % Time vector
            f = app.Fs*(0:(app.L/2))/app.L;
            [modemap,ampmode,Peaks,Locations,specAmps,maskSpectrum]=computeCBFperPixel(app.UIAxes2,app.filt,app.L,app.Fs,app.cutStoN,app.promCut);
            
            cc = bwconncomp(maskSpectrum);
            stats = regionprops(cc, 'Area');
            idx = find([stats.Area] > app.areaThresh);
            maskSpectrum= ismember(labelmatrix(cc), idx);
            % output excel files
            for i=1:size(modemap,3)
                warning('off')
                clear T1
                curmod=modemap(:,:,i);
                curamp=ampmode(:,:,i);
                T1=table(curmod);
                writetable(T1,[app.fname(1:end-4) '_Modes.xlsx'],'Sheet',['f' num2str(i)]);
                clear T1
                T1=table(curamp);
                writetable(T1,[app.fname(1:end-4) '_Modes.xlsx'],'Sheet',['amp' num2str(i)]);
            end
            
            % Now Average Spectrum
            aveSpectrum=squeeze(nansum(nansum((specAmps.*repmat(maskSpectrum,[1 1 size(specAmps,3)])),1),2))/length(find(maskSpectrum));
            spectouse=aveSpectrum;
            freqtouse=f(3:end-1);
            minHeight=app.promCut;%median(spectouse);%+std(spectouse);
            [peak,loc]=findpeaks(spectouse,freqtouse,'MinPeakHeight',minHeight,'MinPeakDistance',3);
            indfilt=find(spectouse<min(peak));
            minHeight=median(spectouse(indfilt))+std(spectouse(indfilt));
            [peak,loc,w,p]=findpeaks(spectouse,freqtouse,'MinPeakHeight',minHeight,'MinPeakDistance',3);
            ind1=find((peak/std(spectouse(indfilt)))>app.cutStoN);
            ind2=find(p>app.promCut);
            indp=intersect(ind1,ind2);
            % save image
            cla(app.UIAxes)
            app.UIAxes.YDir='normal';
            plot(app.UIAxes,freqtouse,spectouse)
            hold(app.UIAxes,"on");
            xlabel(app.UIAxes,'f (Hz)','FontSize',20)
            ylabel(app.UIAxes,'Spectrum','FontSize',20)
            ylim(app.UIAxes,[0,1.2*max(spectouse)])
            xlim(app.UIAxes,[0,1.2*max(freqtouse)])
            plot(app.UIAxes,loc(indp),peak(indp),'r*')
            A=peak(indp);
            f=loc(indp);
            [Aa,indA]=sort(A,'descend');
            hold(app.UIAxes,"on");
            for k=1:length(indA)
                text(app.UIAxes,double(f(indA(k))+2),double(Aa(k)),['f' num2str(k) '=' num2str(f(indA(k)))],'FontSize',20)
                hold(app.UIAxes,"on");
            end
            exportgraphics(app.UIAxes,[app.fname(1:end-4) '_AverageSpectrum.png'])
            % export in excel
            T1=table(freqtouse',spectouse,'VariableNames',{'freq','spectrum'});
            writetable(T1,[app.fname(1:end-4) '_AveSpectrum.xlsx'],'Sheet','AveSpectrum');
            T1=table(f(indA)',Aa,'VariableNames',{'frequencies','Amplitude'});
            writetable(T1,[app.fname(1:end-4) '_AveSpectrum.xlsx'],'Sheet','Modes');
            
            % saving all to mat file
            %save([app.fname(1:end-4) '_workspace.mat'])
            
        end

        % Value changed function: WhatMovieTypeToExportDropDown
        function WhatMovieTypeToExportDropDownValueChanged(app, event)
            value = app.WhatMovieTypeToExportDropDown.Value;
            app.movType=value;
        end

        % Value changed function: 
        % ApplyImmobileFilterToImageSeriesButton
        function ApplyImmobileFilterToImageSeriesButtonValueChanged(app, event)
            value = app.ApplyImmobileFilterToImageSeriesButton.Value;
            app.ImmobFilt2=value;
            if app.ImmobFilt2==1
                % filtering immobile component
                app.filt = fft(double(app.im),[],3);
                avPixInt = mean(mean(app.filt(:,:,1)))/size(app.filt,3);
                app.filt(:,:,1) = 0;
                app.filt = (real(ifft(app.filt,[],3))+avPixInt);
                app.filt=single(app.filt);
            elseif app.ImmobFilt2==0
                app.filt=app.im;
            end
            cla(app.UIAxes)
            imagesc(app.UIAxes,squeeze(app.filt(:,:,app.sliderVal)));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.filt,2)])
            ylim(app.UIAxes,[1 size(app.filt,1)])
        end

        % Value changed function: 
        % GaussianDenoisingFilterSigmapixelsEditField
        function GaussianDenoisingFilterSigmapixelsEditFieldValueChanged(app, event)
            value = app.GaussianDenoisingFilterSigmapixelsEditField.Value;
            app.gausfilt=value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            [app.filt,h2]=smoothSeries(app.filt,app.gausfilt,'g','p');
            app.ApplyGaussian=1;
        end

        % Value changed function: 
        % WhatFrameRateDoYouWantToSaveVideofpsEditField
        function WhatFrameRateDoYouWantToSaveVideofpsEditFieldValueChanged(app, event)
            value = app.WhatFrameRateDoYouWantToSaveVideofpsEditField.Value;
            app.movFPS=value;
        end

        % Button pushed function: ExportMovieButton
        function ExportMovieButtonPushed(app, event)
            if strcmp(app.movType,'mp4')
                writerObj1 = VideoWriter([app.fname(1:end-4) num2str(app.movFPS)  'fps.mp4'],'MPEG-4');
            elseif strcmp(app.movType,'avi')
                writerObj1 = VideoWriter([app.fname(1:end-4) num2str(app.movFPS)  'fps.avi'],'Uncompressed AVI');
            end
            writerObj1.FrameRate =app.movFPS;
            open(writerObj1);
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:size(app.filt,3)
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                imagesc(app.UIAxes,app.filt(:,:,i));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,5,10,['t= ' num2str((i*app.timeFrame)) ' s'],'Color',[1 1 1],'Fontsize',30)
                hold(app.UIAxes,"on");
                num10=round(10/app.pixelsize);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,size(app.filt,2)-num10-20:size(app.filt,2)-21,(size(app.filt,1)-5)*ones(1,num10),'-','Color',[1 1 1],'LineWidth',5)
                text(app.UIAxes,size(app.filt,2)-num10-25,(size(app.filt,1)-15),'10 \mum','Color',[1 1 1],'Fontsize',30)
                frame = getframe(app.UIAxes);
                writeVideo(writerObj1,frame);
                ph.XData = [0 i/size(app.filt,3)  i/size(app.filt,3) 0];
                th.String = sprintf('%.0f%%',round(i/size(app.filt,3)*100));
                drawnow %update graphics
            end
            close(writerObj1);
        end

        % Button pushed function: ExportFirst100FramesTestButton
        function ExportFirst100FramesTestButtonPushed(app, event)
            if strcmp(app.movType,'mp4')
                writerObj1 = VideoWriter([app.fname(1:end-4) num2str(app.movFPS)  'fps_100frames.mp4'],'MPEG-4');
            elseif strcmp(app.movType,'avi')
                writerObj1 = VideoWriter([app.fname(1:end-4) num2str(app.movFPS)  'fps_100frames.avi'],'Uncompressed AVI');
            end
            writerObj1.FrameRate =app.movFPS;
            open(writerObj1);
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:100
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                imagesc(app.UIAxes,app.filt(:,:,i));
                colormap(app.UIAxes,'gray')
                hold(app.UIAxes,"on");
                text(app.UIAxes,5,10,['t= ' num2str((i*app.timeFrame)) ' s'],'Color',[1 1 1],'Fontsize',30)
                hold(app.UIAxes,"on");
                num10=round(10/app.pixelsize);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,size(app.filt,2)-num10-20:size(app.filt,2)-21,(size(app.filt,1)-5)*ones(1,num10),'-','Color',[1 1 1],'LineWidth',5)
                text(app.UIAxes,size(app.filt,2)-num10-25,(size(app.filt,1)-15),'10 \mum','Color',[1 1 1],'Fontsize',30)
                frame = getframe(app.UIAxes);
                writeVideo(writerObj1,frame);
                ph.XData = [0 i/100  i/100 0];
                th.String = sprintf('%.0f%%',round(i));
                drawnow %update graphics
            end
            close(writerObj1);
        end

        % Button pushed function: 
        % ApplyBatchCBFAnalysisUsingAboveDefinedParameters1Button
        function ApplyBatchCBFAnalysisUsingAboveDefinedParameters1ButtonPushed(app, event)
            % select files to process
            [files, path] = uigetfile('*.czi;*.nd2;*.oir;*.oif;*.lif','Select all OME files to process','MultiSelect','on');
            % process files one by one
            for file=1:length(files)
                %load file
                fname = [path files{file}];
                app.fname=fname;
                [im,specs]=readOMEAlternative(app.fname);
                app.im=single(im);
                % immobile filter
                if app.ImmobFilt1==1
                    % filtering immobile component
                    app.filt = fft(double(app.im),[],3);
                    avPixInt = mean(mean(app.filt(:,:,1)))/size(app.filt,3);
                    app.filt(:,:,1) = 0;
                    app.filt = (real(ifft(app.filt,[],3))+avPixInt);
                    app.filt=single(app.filt);
                elseif app.ImmobFilt1==0
                    app.filt=app.im;
                end
                
                % define some parameters
                app.Fs = 1/app.timeFrame;            % Sampling frequency
                app.L =  app.TotImages;              % Length of signal
                app.t = (0:app.L-1)*app.timeFrame;        % Time vector
                f = app.Fs*(0:(app.L/2))/app.L;
                [modemap,ampmode,Peaks,Locations,specAmps,maskSpectrum]=computeCBFperPixel(app.UIAxes2,app.filt,app.L,app.Fs,app.cutStoN,app.promCut);
                
                cc = bwconncomp(maskSpectrum);
                stats = regionprops(cc, 'Area');
                idx = find([stats.Area] > app.areaThresh);
                maskSpectrum= ismember(labelmatrix(cc), idx);
                % output excel files
                for i=1:size(modemap,3)
                    warning('off')
                    clear T1
                    curmod=modemap(:,:,i);
                    curamp=ampmode(:,:,i);
                    T1=table(curmod);
                    writetable(T1,[app.fname(1:end-4) '_Modes.xlsx'],'Sheet',['f' num2str(i)]);
                    clear T1
                    T1=table(curamp);
                    writetable(T1,[app.fname(1:end-4) '_Modes.xlsx'],'Sheet',['amp' num2str(i)]);
                end
                
                % Now Average Spectrum
                aveSpectrum=squeeze(nansum(nansum((specAmps.*repmat(maskSpectrum,[1 1 size(specAmps,3)])),1),2))/length(find(maskSpectrum));
                spectouse=aveSpectrum;
                freqtouse=f(3:end-1);
                minHeight=app.promCut;%median(spectouse);%+std(spectouse);
                [peak,loc]=findpeaks(spectouse,freqtouse,'MinPeakHeight',minHeight,'MinPeakDistance',3);
                indfilt=find(spectouse<min(peak));
                minHeight=median(spectouse(indfilt))+std(spectouse(indfilt));
                [peak,loc,w,p]=findpeaks(spectouse,freqtouse,'MinPeakHeight',minHeight,'MinPeakDistance',3);
                ind1=find((peak/std(spectouse(indfilt)))>app.cutStoN);
                ind2=find(p>app.promCut);
                indp=intersect(ind1,ind2);
                % save image
                cla(app.UIAxes)
                app.UIAxes.YDir='normal';
                plot(app.UIAxes,freqtouse,spectouse)
                hold(app.UIAxes,"on");
                xlabel(app.UIAxes,'f (Hz)','FontSize',20)
                ylabel(app.UIAxes,'Spectrum','FontSize',20)
                ylim(app.UIAxes,[0,1.2*max(spectouse)])
                xlim(app.UIAxes,[0,1.2*max(freqtouse)])
                plot(app.UIAxes,loc(indp),peak(indp),'r*')
                A=peak(indp);
                f=loc(indp);
                [Aa,indA]=sort(A,'descend');
                hold(app.UIAxes,"on");
                for k=1:length(indA)
                    text(app.UIAxes,double(f(indA(k))+2),double(Aa(k)),['f' num2str(k) '=' num2str(f(indA(k)))],'FontSize',20)
                    hold(app.UIAxes,"on");
                end
                exportgraphics(app.UIAxes,[app.fname(1:end-4) '_AverageSpectrum.png'])
                % export in excel
                T1=table(freqtouse',spectouse,'VariableNames',{'freq','spectrum'});
                writetable(T1,[app.fname(1:end-4) '_AveSpectrum.xlsx'],'Sheet','AveSpectrum');
                T1=table(f(indA)',Aa,'VariableNames',{'frequencies','Amplitude'});
                writetable(T1,[app.fname(1:end-4) '_AveSpectrum.xlsx'],'Sheet','Modes');
                
                % saving all to mat file
                %  save([app.fname(1:end-4) '_workspace.mat'])
                
            end
        end

        % Button pushed function: 
        % ApplyBatchMovieExportUsingAboveDefinedParameters2Button
        function ApplyBatchMovieExportUsingAboveDefinedParameters2ButtonPushed(app, event)
            % select files to process
            [files, p] = uigetfile('*.czi;*.nd2;*.oir;*.oif;*.lif','Select all OME files to process','MultiSelect','on');
            % process files one by one
            for file=1:length(files)
                %load file
                fname = [p files{file}];
                app.fname=fname;
                [im,specs]=readOMEAlternative(app.fname);
                app.im=single(im);
                % immobile filter
                if app.ImmobFilt2==1 
                    % filtering immobile component
                    app.filt = fft(double(app.im),[],3);
                    avPixInt = mean(mean(app.filt(:,:,1)))/size(app.filt,3);
                    app.filt(:,:,1) = 0;
                    app.filt = (real(ifft(app.filt,[],3))+avPixInt);
                    app.filt=single(app.filt);
                elseif app.ImmobFilt2==0
                    app.filt=app.im;
                end
                % Gaussian filter
                if app.ApplyGaussian==1
                [app.filt,h2]=smoothSeries(app.filt,app.gausfilt,'g','p');
                end
                % export movie
                if strcmp(app.movType,'mp4')
                    writerObj1 = VideoWriter([app.fname(1:end-4) num2str(app.movFPS)  'fps.mp4'],'MPEG-4');
                elseif strcmp(app.movType,'avi')
                    writerObj1 = VideoWriter([app.fname(1:end-4) num2str(app.movFPS)  'fps.avi'],'Uncompressed AVI');
                end
                writerObj1.FrameRate =app.movFPS;
                open(writerObj1);
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for i=1:size(app.filt,3)
                    cla(app.UIAxes)
                    app.UIAxes.YDir='reverse';
                    xlabel(app.UIAxes,'')
                    ylabel(app.UIAxes,'')
                    imagesc(app.UIAxes,app.filt(:,:,i));
                    colormap(app.UIAxes,'gray')
                    hold(app.UIAxes,"on");
                    text(app.UIAxes,5,10,['t= ' num2str((i*app.timeFrame)) ' s'],'Color',[1 1 1],'Fontsize',30)
                    hold(app.UIAxes,"on");
                    num10=round(10/app.pixelsize);
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,size(app.filt,2)-num10-20:size(app.filt,2)-21,(size(app.filt,1)-5)*ones(1,num10),'-','Color',[1 1 1],'LineWidth',5)
                    text(app.UIAxes,size(app.filt,2)-num10-25,(size(app.filt,1)-15),'10 \mum','Color',[1 1 1],'Fontsize',30)
                    frame = getframe(app.UIAxes);
                    writeVideo(writerObj1,frame);
                    ph.XData = [0 i/size(app.filt,3)  i/size(app.filt,3) 0];
                    th.String = sprintf('%.0f%%',round(i/size(app.filt,3)*100));
                    drawnow %update graphics
                end
                close(writerObj1);
                
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadOMEfileButton = uibutton(app.UIFigure, 'push');
            app.LoadOMEfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOMEfileButtonPushed, true);
            app.LoadOMEfileButton.Text = 'Load OME file';
            app.CBFComputationParametersPanel = uipanel(app.UIFigure);
            app.CBFComputationParametersPanel.Title = '1) CBF Computation Parameters';
            app.FrametimesecondsEditFieldLabel = uilabel(app.CBFComputationParametersPanel);
            app.FrametimesecondsEditFieldLabel.Text = 'Frame time (seconds)';
            app.FrametimesecondsEditField = uieditfield(app.CBFComputationParametersPanel, 'numeric');
            app.FrametimesecondsEditField.Limits = [0 Inf];
            app.FrametimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @FrametimesecondsEditFieldValueChanged, true);
            app.FrametimesecondsEditField.Value = 0.003;
            app.SignalToNoiseCutoffPeakDetectionEditFieldLabel = uilabel(app.CBFComputationParametersPanel);
            app.SignalToNoiseCutoffPeakDetectionEditFieldLabel.Text = 'Signal-To-Noise Cutoff Peak Detection';
            app.SignalToNoiseCutoffPeakDetectionEditField = uieditfield(app.CBFComputationParametersPanel, 'numeric');
            app.SignalToNoiseCutoffPeakDetectionEditField.ValueChangedFcn = createCallbackFcn(app, @SignalToNoiseCutoffPeakDetectionEditFieldValueChanged, true);
            app.SignalToNoiseCutoffPeakDetectionEditField.Value = 5;
            app.PeakProminenceCutoffEditFieldLabel = uilabel(app.CBFComputationParametersPanel);
            app.PeakProminenceCutoffEditFieldLabel.Text = 'Peak Prominence Cutoff';
            app.PeakProminenceCutoffEditField = uieditfield(app.CBFComputationParametersPanel, 'numeric');
            app.PeakProminenceCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @PeakProminenceCutoffEditFieldValueChanged, true);
            app.PeakProminenceCutoffEditField.Value = 0.05;
            app.CutoffAreaCBFMaskEditFieldLabel = uilabel(app.CBFComputationParametersPanel);
            app.CutoffAreaCBFMaskEditFieldLabel.Text = 'Cutoff Area CBF Mask';
            app.CutoffAreaCBFMaskEditField = uieditfield(app.CBFComputationParametersPanel, 'numeric');
            app.CutoffAreaCBFMaskEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffAreaCBFMaskEditFieldValueChanged, true);
            app.CutoffAreaCBFMaskEditField.Value = 10;
            app.ComputeCBFButton = uibutton(app.CBFComputationParametersPanel, 'push');
            app.ComputeCBFButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCBFButtonPushed, true);
            app.ComputeCBFButton.Text = 'Compute CBF';
            app.PixelSizemicrometerEditFieldLabel = uilabel(app.CBFComputationParametersPanel);
            app.PixelSizemicrometerEditFieldLabel.Text = 'Pixel Size (micrometer)';
            app.PixelSizemicrometerEditField = uieditfield(app.CBFComputationParametersPanel, 'numeric');
            app.PixelSizemicrometerEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemicrometerEditFieldValueChanged, true);
            app.PixelSizemicrometerEditField.Value = 0.5;
            app.ApplyImmobileFilterToImageSeriesButton_2 = uibutton(app.CBFComputationParametersPanel, 'state');
            app.ApplyImmobileFilterToImageSeriesButton_2.ValueChangedFcn = createCallbackFcn(app, @ApplyImmobileFilterToImageSeriesButton_2ValueChanged, true);
            app.ApplyImmobileFilterToImageSeriesButton_2.Text = 'Apply Immobile Filter To Image Series?';
            app.frameSliderLabel = uilabel(app.UIFigure);
            app.frameSliderLabel.Text = 'frame';
            app.frameSlider = uislider(app.UIFigure);
            app.frameSlider.Limits = [1 100];
            app.frameSlider.ValueChangedFcn = createCallbackFcn(app, @frameSliderValueChanged, true);
            app.frameSlider.ValueChangingFcn = createCallbackFcn(app, @frameSliderValueChanging, true);
            app.frameSlider.Value = 1;
            app.ExportMoviePanel = uipanel(app.UIFigure);
            app.ExportMoviePanel.Title = '2) Export Movie';
            app.WhatMovieTypeToExportLabel = uilabel(app.ExportMoviePanel);
            app.WhatMovieTypeToExportLabel.Text = 'What Movie Type To Export?';
            app.WhatMovieTypeToExportDropDown = uidropdown(app.ExportMoviePanel);
            app.WhatMovieTypeToExportDropDown.Items = {'mp4', 'avi'};
            app.WhatMovieTypeToExportDropDown.ValueChangedFcn = createCallbackFcn(app, @WhatMovieTypeToExportDropDownValueChanged, true);
            app.WhatMovieTypeToExportDropDown.Value = 'mp4';
            app.WhatFrameRateDoYouWantToSaveVideofpsEditFieldLabel = uilabel(app.ExportMoviePanel);
            app.WhatFrameRateDoYouWantToSaveVideofpsEditFieldLabel.Text = 'What Frame Rate Do You Want To Save Video (fps)';
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField = uieditfield(app.ExportMoviePanel, 'numeric');
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField.ValueChangedFcn = createCallbackFcn(app, @WhatFrameRateDoYouWantToSaveVideofpsEditFieldValueChanged, true);
            app.WhatFrameRateDoYouWantToSaveVideofpsEditField.Value = 30;
            app.ApplyImmobileFilterToImageSeriesButton = uibutton(app.ExportMoviePanel, 'state');
            app.ApplyImmobileFilterToImageSeriesButton.ValueChangedFcn = createCallbackFcn(app, @ApplyImmobileFilterToImageSeriesButtonValueChanged, true);
            app.ApplyImmobileFilterToImageSeriesButton.Text = 'Apply Immobile Filter To Image Series?';
            app.ExportMovieButton = uibutton(app.ExportMoviePanel, 'push');
            app.ExportMovieButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMovieButtonPushed, true);
            app.ExportMovieButton.Text = 'Export Movie';
            app.ExportFirst100FramesTestButton = uibutton(app.ExportMoviePanel, 'push');
            app.ExportFirst100FramesTestButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFirst100FramesTestButtonPushed, true);
            app.ExportFirst100FramesTestButton.Text = 'Export First 100 Frames Test';
            app.GaussianDenoisingFilterSigmapixelsEditFieldLabel = uilabel(app.ExportMoviePanel);
            app.GaussianDenoisingFilterSigmapixelsEditFieldLabel.Text = 'Gaussian Denoising Filter Sigma (pixels)';
            app.GaussianDenoisingFilterSigmapixelsEditField = uieditfield(app.ExportMoviePanel, 'numeric');
            app.GaussianDenoisingFilterSigmapixelsEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianDenoisingFilterSigmapixelsEditFieldValueChanged, true);
            app.GaussianDenoisingFilterSigmapixelsEditField.Value = 1.5;
            app.ApplyButton = uibutton(app.ExportMoviePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply!';
            app.ApplyBatchCBFAnalysisUsingAboveDefinedParameters1Button = uibutton(app.UIFigure, 'push');
            app.ApplyBatchCBFAnalysisUsingAboveDefinedParameters1Button.ButtonPushedFcn = createCallbackFcn(app, @ApplyBatchCBFAnalysisUsingAboveDefinedParameters1ButtonPushed, true);
            app.ApplyBatchCBFAnalysisUsingAboveDefinedParameters1Button.Text = 'Apply Batch CBF Analysis Using Above Defined Parameters 1';
            app.ApplyBatchMovieExportUsingAboveDefinedParameters2Button = uibutton(app.UIFigure, 'push');
            app.ApplyBatchMovieExportUsingAboveDefinedParameters2Button.ButtonPushedFcn = createCallbackFcn(app, @ApplyBatchMovieExportUsingAboveDefinedParameters2ButtonPushed, true);
            app.ApplyBatchMovieExportUsingAboveDefinedParameters2Button.Text = 'Apply Batch Movie Export Using Above Defined Parameters 2';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CBFcalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end